classdef Session_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadSessionMenu                matlab.ui.container.Menu
        RunpipelineMenu                matlab.ui.container.Menu
        BatchMenu                      matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        DrawRoi                        matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.ToggleTool
        TabGroup                       matlab.ui.container.TabGroup
        SessionTab                     matlab.ui.container.Tab
        ProjectionDropDown             matlab.ui.control.DropDown
        ProjectionDropDownLabel        matlab.ui.control.Label
        ChSpinner                      matlab.ui.control.Spinner
        ChSpinnerLabel                 matlab.ui.control.Label
        Slider_time                    matlab.ui.control.Slider
        ImgProjection                  matlab.ui.control.UIAxes
        Traces                         matlab.ui.control.UIAxes
        Image                          matlab.ui.control.UIAxes
        AnalogDataTab                  matlab.ui.container.Tab
        plot_STIM                      matlab.ui.control.UIAxes
        plot_ECG                       matlab.ui.control.UIAxes
        plot_TTL                       matlab.ui.control.UIAxes
        ParamsTab                      matlab.ui.container.Tab
        ParametersImagingdataPanel     matlab.ui.container.Panel
        NumberofchannelsDropDown       matlab.ui.control.DropDown
        NumberofchannelsDropDownLabel  matlab.ui.control.Label
        MotionCorrectionChDropDown     matlab.ui.control.DropDown
        MotionCorrectionChDropDownLabel  matlab.ui.control.Label
        SignalChDropDownLabel          matlab.ui.control.Label
        SignalChDropDown               matlab.ui.control.DropDown
        ParametersAnalogPanel          matlab.ui.container.Panel
        ECGdistanceEditField           matlab.ui.control.NumericEditField
        ECGdistanceEditFieldLabel      matlab.ui.control.Label
        ECGthresholdEditField          matlab.ui.control.NumericEditField
        ECGthresholdEditFieldLabel     matlab.ui.control.Label
        TTLdistanceEditField           matlab.ui.control.NumericEditField
        TTLdistanceEditFieldLabel      matlab.ui.control.Label
        TTLthresholdEditField          matlab.ui.control.NumericEditField
        TTLthresholdEditFieldLabel     matlab.ui.control.Label
        PipelinePanel                  matlab.ui.container.Panel
        ComputePSTHCheckBox            matlab.ui.control.CheckBox
        ComputedffCheckBox             matlab.ui.control.CheckBox
        MotioncorrectCheckBox          matlab.ui.control.CheckBox
        SplitchannelsCheckBox          matlab.ui.control.CheckBox
        LogTab                         matlab.ui.container.Tab
        ExecutionlogTextArea           matlab.ui.control.TextArea
        ContextMenu                    matlab.ui.container.ContextMenu
        DrawROIMenu                    matlab.ui.container.Menu
        ClearallROIsMenu               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        PATH_TO_SESSION % pointer to session folder 
        PATH_TO_DERIVATES % sessioin analysis results will go here
        SESSION_NAME % current session name
        STK % Description
        STK_MC % Motion corrected 
        STK_DF % dF/f data
        PIPELINE % keep track of processes, parametes and excecution status
        ANALOG_TABLE
        STIM_TABLE % stim start/end, if STIM column present in analog data
        FRAME_LUT % frame timing from TTL data, if present
        PROJ_MAX
        PROJ_AVG
        PROJ_STD
        PROJ_MED
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %to do at startup :-)
            
        end

        % Menu selected function: LoadSessionMenu
        function LoadSessionMenuSelected(app, event)
            gather_pipeline_params(app)
            load_single_session(app)
        end

        % Value changed function: ChSpinner, Slider_time
        function Slider_timeValueChanged(app, event)
            display_frame_i = round(app.Slider_time.Value);
            ch_i = app.ChSpinner.Value;
            imagesc(app.Image,app.STK(:,:,display_frame_i + ch_i-1));
            colorbar(app.Image,'eastoutside')

        end

        % Callback function: DrawROIMenu, DrawRoi
        function DrawROIMenuSelected(app, event)
            add_roi(app)
        end

        % Value changed function: ProjectionDropDown
        function ProjectionDropDownValueChanged(app, event)
            update_proj_display(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadSessionMenu = uimenu(app.FileMenu);
            app.LoadSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSessionMenuSelected, true);
            app.LoadSessionMenu.Accelerator = 'o';
            app.LoadSessionMenu.Text = 'Load Session';
            app.RunpipelineMenu = uimenu(app.FileMenu);
            app.RunpipelineMenu.Accelerator = 'r';
            app.RunpipelineMenu.Text = 'Run pipeline';
            app.BatchMenu = uimenu(app.UIFigure);
            app.BatchMenu.Text = 'Batch';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.DrawRoi = uipushtool(app.Toolbar);
            app.DrawRoi.Tooltip = {'Draw ROI'};
            app.DrawRoi.ClickedCallback = createCallbackFcn(app, @DrawROIMenuSelected, true);
            app.PushTool2 = uitoggletool(app.Toolbar);
            app.PushTool2.Tooltip = {'Live update traces'};
            app.PushTool2.Separator = 'on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SessionTab = uitab(app.TabGroup);
            app.SessionTab.Title = 'Session';
            app.Image = uiaxes(app.SessionTab);
            zlabel(app.Image, 'Z')
            app.Traces = uiaxes(app.SessionTab);
            title(app.Traces, 'Title')
            xlabel(app.Traces, 'X')
            ylabel(app.Traces, 'Y')
            zlabel(app.Traces, 'Z')
            app.ImgProjection = uiaxes(app.SessionTab);
            zlabel(app.ImgProjection, 'Z')
            app.Slider_time = uislider(app.SessionTab);
            app.Slider_time.ValueChangedFcn = createCallbackFcn(app, @Slider_timeValueChanged, true);
            app.ChSpinnerLabel = uilabel(app.SessionTab);
            app.ChSpinnerLabel.Text = 'Ch';
            app.ChSpinner = uispinner(app.SessionTab);
            app.ChSpinner.Limits = [1 4];
            app.ChSpinner.ValueChangedFcn = createCallbackFcn(app, @Slider_timeValueChanged, true);
            app.ChSpinner.Tooltip = {'Channel to display'};
            app.ChSpinner.Value = 1;
            app.ProjectionDropDownLabel = uilabel(app.SessionTab);
            app.ProjectionDropDownLabel.Tooltip = {'Select projection to display'};
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.SessionTab);
            app.ProjectionDropDown.Items = {'Max', 'Mean', 'Std', 'Median'};
            app.ProjectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDownValueChanged, true);
            app.ProjectionDropDown.Value = 'Max';
            app.AnalogDataTab = uitab(app.TabGroup);
            app.AnalogDataTab.Title = 'Analog Data';
            app.plot_TTL = uiaxes(app.AnalogDataTab);
            title(app.plot_TTL, 'TTL')
            xlabel(app.plot_TTL, 'Time (s)')
            ylabel(app.plot_TTL, 'V')
            zlabel(app.plot_TTL, 'Z')
            app.plot_ECG = uiaxes(app.AnalogDataTab);
            title(app.plot_ECG, 'ECG')
            xlabel(app.plot_ECG, 'Time (s)')
            ylabel(app.plot_ECG, 'V')
            zlabel(app.plot_ECG, 'Z')
            app.plot_STIM = uiaxes(app.AnalogDataTab);
            title(app.plot_STIM, 'STIM')
            xlabel(app.plot_STIM, 'Time (s)')
            ylabel(app.plot_STIM, 'V')
            zlabel(app.plot_STIM, 'Z')
            app.ParamsTab = uitab(app.TabGroup);
            app.ParamsTab.Title = 'Params';
            app.PipelinePanel = uipanel(app.ParamsTab);
            app.PipelinePanel.Title = 'Pipeline';
            app.SplitchannelsCheckBox = uicheckbox(app.PipelinePanel);
            app.SplitchannelsCheckBox.Text = 'Split channels';
            app.SplitchannelsCheckBox.Value = true;
            app.MotioncorrectCheckBox = uicheckbox(app.PipelinePanel);
            app.MotioncorrectCheckBox.Text = 'Motion correct';
            app.MotioncorrectCheckBox.Value = true;
            app.ComputedffCheckBox = uicheckbox(app.PipelinePanel);
            app.ComputedffCheckBox.Text = 'Compute df/f';
            app.ComputedffCheckBox.Value = true;
            app.ComputePSTHCheckBox = uicheckbox(app.PipelinePanel);
            app.ComputePSTHCheckBox.Text = 'Compute PSTH';
            app.ComputePSTHCheckBox.Value = true;
            app.ParametersAnalogPanel = uipanel(app.ParamsTab);
            app.ParametersAnalogPanel.Title = 'Parameters Analog ';
            app.TTLthresholdEditFieldLabel = uilabel(app.ParametersAnalogPanel);
            app.TTLthresholdEditFieldLabel.Text = 'TTL threshold';
            app.TTLthresholdEditField = uieditfield(app.ParametersAnalogPanel, 'numeric');
            app.TTLthresholdEditField.Tooltip = {'Voltage threshold (V)'};
            app.TTLthresholdEditField.Value = 3;
            app.TTLdistanceEditFieldLabel = uilabel(app.ParametersAnalogPanel);
            app.TTLdistanceEditFieldLabel.Text = 'TTL distance';
            app.TTLdistanceEditField = uieditfield(app.ParametersAnalogPanel, 'numeric');
            app.TTLdistanceEditField.Tooltip = {'Minimal distance between TTLs (in milliseconds)'};
            app.TTLdistanceEditField.Value = 5;
            app.ECGthresholdEditFieldLabel = uilabel(app.ParametersAnalogPanel);
            app.ECGthresholdEditFieldLabel.Text = 'ECG threshold';
            app.ECGthresholdEditField = uieditfield(app.ParametersAnalogPanel, 'numeric');
            app.ECGthresholdEditField.Tooltip = {'ECG voltage threshold'};
            app.ECGthresholdEditField.Value = 0.2;
            app.ECGdistanceEditFieldLabel = uilabel(app.ParametersAnalogPanel);
            app.ECGdistanceEditFieldLabel.Tooltip = {'Minimal distance between ECG peaks (in milliseconds)'};
            app.ECGdistanceEditFieldLabel.Text = 'ECG distance';
            app.ECGdistanceEditField = uieditfield(app.ParametersAnalogPanel, 'numeric');
            app.ECGdistanceEditField.Value = 50;
            app.ParametersImagingdataPanel = uipanel(app.ParamsTab);
            app.ParametersImagingdataPanel.Title = 'Parameters Imaging data';
            app.SignalChDropDown = uidropdown(app.ParametersImagingdataPanel);
            app.SignalChDropDown.Items = {'1', '2', '3'};
            app.SignalChDropDown.Value = '1';
            app.SignalChDropDownLabel = uilabel(app.ParametersImagingdataPanel);
            app.SignalChDropDownLabel.Text = 'Functional Signal  Ch';
            app.MotionCorrectionChDropDownLabel = uilabel(app.ParametersImagingdataPanel);
            app.MotionCorrectionChDropDownLabel.Text = 'Motion Correction Ch';
            app.MotionCorrectionChDropDown = uidropdown(app.ParametersImagingdataPanel);
            app.MotionCorrectionChDropDown.Items = {'1', '2', '3'};
            app.MotionCorrectionChDropDown.Value = '1';
            app.NumberofchannelsDropDownLabel = uilabel(app.ParametersImagingdataPanel);
            app.NumberofchannelsDropDownLabel.Text = 'Number of channels';
            app.NumberofchannelsDropDown = uidropdown(app.ParametersImagingdataPanel);
            app.NumberofchannelsDropDown.Items = {'1', '2', '3'};
            app.NumberofchannelsDropDown.Value = '2';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.ExecutionlogTextArea = uitextarea(app.LogTab);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DrawROIMenu = uimenu(app.ContextMenu);
            app.DrawROIMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawROIMenuSelected, true);
            app.DrawROIMenu.Text = 'Draw ROI';
            app.ClearallROIsMenu = uimenu(app.ContextMenu);
            app.ClearallROIsMenu.Text = 'Clear all ROIs';
            % Assign app.ContextMenu
            app.Image.ContextMenu = app.ContextMenu;
            app.ImgProjection.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Session_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end